classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SignalTab                     matlab.ui.container.Tab
        Label_2                       matlab.ui.control.Label
        SignalLabel                   matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        PolyinputsPanel               matlab.ui.container.Panel
        EnterButton                   matlab.ui.control.Button
        coefficientEditField          matlab.ui.control.NumericEditField
        coefficientEditFieldLabel     matlab.ui.control.Label
        n_coefficientEditField        matlab.ui.control.NumericEditField
        n_coefficientEditFieldLabel   matlab.ui.control.Label
        SignalDropDown                matlab.ui.control.DropDown
        SignalDropDownLabel           matlab.ui.control.Label
        data_paramButton              matlab.ui.control.Button
        SetButton                     matlab.ui.control.Button
        RampPanel                     matlab.ui.container.Panel
        InterceptEditField            matlab.ui.control.NumericEditField
        InterceptEditFieldLabel       matlab.ui.control.Label
        SlopeEditField                matlab.ui.control.NumericEditField
        SlopeEditFieldLabel           matlab.ui.control.Label
        SincinputsPanel               matlab.ui.container.Panel
        Center_shiftEditField_2       matlab.ui.control.NumericEditField
        Center_shiftEditField_2Label  matlab.ui.control.Label
        AmplitudeEditField_5          matlab.ui.control.NumericEditField
        AmplitudeEditField_5Label     matlab.ui.control.Label
        ExpinputsPanel                matlab.ui.container.Panel
        ExponentEditField             matlab.ui.control.NumericEditField
        ExponentEditFieldLabel        matlab.ui.control.Label
        AmplitudeEditField_4          matlab.ui.control.NumericEditField
        AmplitudeEditField_4Label     matlab.ui.control.Label
        SininputsPanel                matlab.ui.container.Panel
        PhaseEditField                matlab.ui.control.NumericEditField
        PhaseEditFieldLabel           matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField_3          matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label     matlab.ui.control.Label
        TriangleinputsPanel           matlab.ui.container.Panel
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        Center_shiftEditField         matlab.ui.control.NumericEditField
        Center_shiftEditFieldLabel    matlab.ui.control.Label
        AmplitudeEditField_2          matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label     matlab.ui.control.Label
        DcinputsPanel                 matlab.ui.container.Panel
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        signal_dataButton             matlab.ui.control.Button
        set_dataButton                matlab.ui.control.Button
        No_BreakPointsEditField       matlab.ui.control.NumericEditField
        No_BreakPointsEditFieldLabel  matlab.ui.control.Label
        Break_PointEditField          matlab.ui.control.NumericEditField
        Break_PointEditFieldLabel     matlab.ui.control.Label
        FsEditField                   matlab.ui.control.NumericEditField
        FsEditFieldLabel              matlab.ui.control.Label
        End_timeEditField             matlab.ui.control.NumericEditField
        End_timeEditFieldLabel        matlab.ui.control.Label
        Start_timeEditField           matlab.ui.control.NumericEditField
        Start_timeEditFieldLabel      matlab.ui.control.Label
        VisualTab                     matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        OperationTab                  matlab.ui.container.Tab
        ClippingPanel                 matlab.ui.container.Panel
        LowerEditField                matlab.ui.control.NumericEditField
        LowerEditFieldLabel           matlab.ui.control.Label
        UpperEditField                matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        CompressinputsPanel           matlab.ui.container.Panel
        Compress_valEditField         matlab.ui.control.NumericEditField
        Compress_valEditFieldLabel    matlab.ui.control.Label
        ExpandinputsPanel             matlab.ui.container.Panel
        Expand_valEditField           matlab.ui.control.NumericEditField
        Expand_valEditFieldLabel      matlab.ui.control.Label
        Time_shiftinginputsPanel      matlab.ui.container.Panel
        ShiftEditField                matlab.ui.control.NumericEditField
        ShiftEditFieldLabel           matlab.ui.control.Label
        Amp_scalinginputsPanel        matlab.ui.container.Panel
        ScaleEditField                matlab.ui.control.NumericEditField
        ScaleEditFieldLabel           matlab.ui.control.Label
        OperationButtonGroup          matlab.ui.container.ButtonGroup
        NoneButton                    matlab.ui.control.RadioButton
        derivativeButton              matlab.ui.control.RadioButton
        clippingButton                matlab.ui.control.RadioButton
        CompressButton                matlab.ui.control.RadioButton
        ExpandButton                  matlab.ui.control.RadioButton
        Time_reverseButton            matlab.ui.control.RadioButton
        Time_shiftingButton           matlab.ui.control.RadioButton
        Amp_scalingButton             matlab.ui.control.RadioButton
        startButton                   matlab.ui.control.RadioButton
        UIAxes2_2                     matlab.ui.control.UIAxes
        operation_visualTab           matlab.ui.container.Tab
        UIAxes2                       matlab.ui.control.UIAxes
    end

   properties (Access = public)
     %****************** parameters for setting the data *******************%
    n_break=0
    breaks_num 
    set_data
    break_points=[]
    signal_type=[]
    signal
    %****************** parameters for getting the changed values the complete signal *****%
    complete_signal=[]
    amp_dc=0
    slope_ramp=0
    intercept_ramp=0
    coeff=0
    coeff_poly=[]
    n_coeff=0
    amp_exp=0
    expon=0
    amp_sin=0
    freq_sin=0
    phase_sin=0
    amp_sinc=0
    center_shift_sinc=0
    amp_triangle=0
    center_shift_triangle=0
    width_triangle=0
    flag=0
    %******************* sending parameters to fn to plot the signal *************************%
    
    amp_dc_send=0
    slope_ramp_send=0
    intercept_ramp_send=0
    coeff_poly_send=[]
    amp_exp_send=0
    expon_send=0
    amp_sin_send=0
    freq_sin_send=0
    phase_sin_send=0
    amp_sinc_send=0
    center_shift_sinc_send=0
    amp_triangle_send=0
    center_shift_triangle_send=0
    width_triangle_send=0
    %******************** for looping ***********************%
    i=0 %system
    j=1 %poly
    %********************************************************%
    start_time=0
    end_time=0
    fs=0
    t=0
    last_break=0
    
    %********************** modulation *********************%
    operation="null"
    scale=1
    shift=0
    Expand_val=1
    Compress_val=1
    upper=0
    lower=0
    modulate_signal=[]
    modulate_t
    st=0
    tf=0
    freq_sample=0
    
    
    select_signal_counter = 1
    set_signal_counter = 1


   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   
    % Code that executes after component creation

 
    if app.n_break >= 0
        if app.n_break>0
            app.set_dataButton.Enable = true;  % Enable breakEditField
            app.signal_dataButton.Enable=false;
        else
            app.set_dataButton.Enable = false;  % Enable breakEditField
            app.signal_dataButton.Enable=true;
        end
        if length(app.break_points)==app.n_break
            app.signal_dataButton.Enable=true;
            app.set_dataButton.Enable=false;
            if (length(app.signal_type)==app.n_break+1)
                app.signal_dataButton.Enable=false;
            end
        
    
        end
         if app.i == app.n_break+1
                    app.SetButton.Enable=false;
         else
             app.SetButton.Enable=true;
         end
            
        
    else 
        % Display an error message and disable breakEditField
        app.set_dataButton.Enable = false;
        
    end
  app.t = linspace(app.start_time, app.end_time, round((app.end_time-app.start_time)*app.fs));
  
   if app.i~=0
  
    region_start=app.start_time;
   if isempty(app.break_points)
       region_end=app.end_time;
   
   elseif app.i <= length(app.break_points)
        region_end = app.break_points(app.i);
   end
   if app.i > 1 && length(app.break_points)>=1
       if length(app.break_points)==app.i-1
        region_start = app.break_points(app.i - 1);
        region_end = app.end_time; 
       else
           
        region_start = app.break_points(app.i - 1);
        region_end = app.break_points(app.i); 
       end
   end
    
   
    region = (app.t >= region_start) & (app.t <= region_end);
   if app.i<=length(app.signal_type)
       
  
    switch app.signal_type(app.i)
        
        case "Dc"
            if app.flag
                
            app.complete_signal(region) = dc_generate(app.t(region),app.amp_dc_send);
            
      
             end
        case "Ramp"
          if app.flag
            
              
          app.complete_signal( region) = Ramp_generate(app.slope_ramp_send,app.intercept_ramp_send,app.t(region));
      
          end
        case "Poly"
           if app.flag
             
          app.complete_signal(region)= poly_generate(app.coeff_poly_send,app.t(region));
    
           end
        case "Exp"
             if app.flag
                
            
            app.complete_signal(region) = exp_generate(app.amp_exp_send,app.expon_send,app.t(region));
                  
             end
        case "Sin"
             if app.flag
               
            app.complete_signal(region) = sin_generate(app.amp_sin_send,app.freq_sin_send,app.phase_sin_send,app.t(region));
   
             end
        case "Sinc"
             if app.flag
                 
            app.complete_signal(region) = sinc_generate(app.amp_sinc_send,app.center_shift_sinc_send,app.t(region));
          
             end
        case "Triangle"
             if app.flag
                 
            app.complete_signal(region) =triangle_generate(app.amp_triangle_send,app.center_shift_triangle_send,app.width_triangle_send,app.t(region));
      
             end
           
        
    end
    app.flag=0;
   end
   else
       app.complete_signal=zeros(size(app.t));
   end


 plot(app.UIAxes,app.t,app.complete_signal);
 disp(length(app.complete_signal));
 
 %**************** operation_condition ***********************%
 app.modulate_signal=app.complete_signal;
 app.modulate_t=app.t;

 if app.operation ~= "null"

     switch app.operation
         case "Amp_scaling"
           
            
           app.modulate_signal= Amp_scale(app.complete_signal,app.scale);
        
           
         case "Time_shifting"
              
             app.modulate_t=shifting_func(app.t,app.shift);
    
         case "Time_reverse"
             
             [app.modulate_signal,app.modulate_t]=time_reverse(app.complete_signal,app.start_time,app.end_time);
             
         case "Expand"
             
             [app.modulate_signal,app.modulate_t]= expand_signal(app.complete_signal,app.start_time,app.end_time,app.signal_type,app.break_points,app.t,app.Expand_val);
             
             
         case "Compress"
              
             [app.modulate_signal,app.modulate_t]=compress_signal(app.complete_signal,app.start_time,app.end_time,app.Compress_val);
            
         case "clipping"
              
             app.modulate_signal= clipping(app.complete_signal,app.lower,app.upper);
            
         case "derivative"
              
             [app.modulate_signal,app.modulate_t]= differentiation(app.complete_signal,app.t,app.fs);
            
         case "None"
              
              app.modulate_signal=app.complete_signal;
              app.modulate_t=app.t; 
              
             
     end
     % Update X and Y axis limits
minimum_x = min(app.modulate_t);
maximum_x = max(app.modulate_t);
minimum_y = min(app.modulate_signal);
maximum_y = max(app.modulate_signal);
if (maximum_x>minimum_x) && (maximum_y>minimum_y)


app.UIAxes2.XLim = [minimum_x, maximum_x];
app.UIAxes2.YLim = [minimum_y, maximum_y];
end

% Plot the data
plot(app.UIAxes2, app.modulate_t, app.modulate_signal);
plot(app.UIAxes2_2, app.modulate_t, app.modulate_signal);


 end


        end

        % Value changed function: Break_PointEditField
        function Break_PointEditFieldValueChanged(app, event)
 
            app.breaks_num = app.Break_PointEditField.Value;
            startupFcn(app);
            
            
        end

        % Value changed function: No_BreakPointsEditField
        function No_BreakPointsEditFieldValueChanged(app, event)
            app.n_break = app.No_BreakPointsEditField.Value;
            startupFcn(app);
           
            
        end

        % Button pushed function: set_dataButton
        function set_dataButtonPushed(app, event)
          
       
       if length(app.break_points)~= app.n_break 
           if (app.breaks_num > app.start_time && app.breaks_num < app.end_time)
                app.break_points=[app.break_points app.breaks_num];
                app.last_break=app.breaks_num;
                app.break_points=sort(app.break_points);
           else
               
               errordlg("Try again with valid inputs ");
           end   
       end
      startupFcn(app);
      
           
        end

        % Callback function
        function signalsButtonGroupSelectionChanged(app, event)
            app.signal= app.signalsButtonGroup.SelectedObject.Text;
          
        end

        % Button pushed function: signal_dataButton
        function signal_dataButtonPushed(app, event)
            if length(app.signal_type)~= app.n_break+1
                if isempty(app.signal)
                    app.signal = "Dc";
                end
                app.signal_type=[string(app.signal_type)  string(app.signal)];
                app.select_signal_counter = app.select_signal_counter + 1;
                app.Label.Text = string(app.select_signal_counter);
                if app.select_signal_counter >= app.n_break + 2
                    app.Label.Text = "";
                end
                
            end
        
            startupFcn(app);
            
          
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.amp_dc = app.AmplitudeEditField.Value;
          
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            app.slope_ramp= app.SlopeEditField.Value;
        
        end

        % Value changed function: InterceptEditField
        function InterceptEditFieldValueChanged(app, event)
            app.intercept_ramp = app.InterceptEditField.Value;
         
        end

        % Value changed function: coefficientEditField
        function coefficientEditFieldValueChanged(app, event)
            app.coeff = app.coefficientEditField.Value;
          
        end

        % Value changed function: AmplitudeEditField_4
        function AmplitudeEditField_4ValueChanged(app, event)
            app.amp_exp = app.AmplitudeEditField_4.Value;
              
        end

        % Value changed function: ExponentEditField
        function ExponentEditFieldValueChanged(app, event)
            app.expon = app.ExponentEditField.Value;
           
            
        end

        % Value changed function: AmplitudeEditField_3
        function AmplitudeEditField_3ValueChanged(app, event)
            app.amp_sin = app.AmplitudeEditField_3.Value;
            
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.freq_sin = app.FrequencyEditField.Value;
           
            
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            app.phase_sin = app.PhaseEditField.Value;
        
            
        end

        % Value changed function: AmplitudeEditField_5
        function AmplitudeEditField_5ValueChanged(app, event)
            app.amp_sinc = app.AmplitudeEditField_5.Value;
            
            
        end

        % Value changed function: Center_shiftEditField_2
        function Center_shiftEditField_2ValueChanged(app, event)
            app.center_shift_sinc = app.Center_shiftEditField_2.Value;
            
            
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            app.amp_triangle = app.AmplitudeEditField_2.Value;
          
            
        end

        % Value changed function: Center_shiftEditField
        function Center_shiftEditFieldValueChanged(app, event)
            app.center_shift_triangle = app.Center_shiftEditField.Value;
         
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.width_triangle = app.WidthEditField.Value;
           
            
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
          
  
                app.amp_dc_send=app.amp_dc;
                app.slope_ramp_send=app.slope_ramp;
                app.intercept_ramp_send=app.intercept_ramp;
                app.coeff_poly_send=app.coeff_poly;
                app.amp_exp_send=app.amp_exp;
                app.expon_send=app.expon;
                app.amp_sin_send=app.amp_sin;
                app.freq_sin_send=app.freq_sin;
                app.phase_sin_send=app.phase_sin;
                app.amp_sinc_send=app.amp_sinc;
                app.center_shift_sinc_send=app.center_shift_sinc;
                app.amp_triangle_send=app.amp_triangle;
                app.center_shift_triangle_send=app.center_shift_triangle;
                app.width_triangle_send=app.width_triangle;
                app.flag=1;
                app.i=app.i+1;

                app.set_signal_counter = app.set_signal_counter + 1;
                app.Label_2.Text = string(app.set_signal_counter);
                if app.set_signal_counter >= app.n_break + 2
                    app.Label_2.Text = "";
                end
              
                startupFcn(app);
         
          
        end

        % Value changed function: n_coefficientEditField
        function n_coefficientEditFieldValueChanged(app, event)
            app.n_coeff = app.n_coefficientEditField.Value;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)

             
            if app.j<=app.n_coeff
              
                app.coeff_poly(app.j)=app.coeff;
                app.j=app.j+1;
                
                
            end
             disp("*****************");
            if app.j>app.n_coeff
                app.EnterButton.Enable=false;
            end
            
            startupFcn(app);
        end

        % Selection changed function: OperationButtonGroup
        function OperationButtonGroupSelectionChanged(app, event)
            app.operation = app.OperationButtonGroup.SelectedObject.Text;
            startupFcn(app);
            
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
          app.scale = app.ScaleEditField.Value;
            startupFcn(app);
        end

        % Value changed function: ShiftEditField
        function ShiftEditFieldValueChanged(app, event)
            app.shift = app.ShiftEditField.Value;
            startupFcn(app);
            
        end

        % Value changed function: Expand_valEditField
        function Expand_valEditFieldValueChanged(app, event)
           app.Expand_val = app.Expand_valEditField.Value;
            startupFcn(app);
            
        end

        % Value changed function: Compress_valEditField
        function Compress_valEditFieldValueChanged(app, event)
            app.Compress_val = app.Compress_valEditField.Value;
             startupFcn(app);
            
        end

        % Value changed function: UpperEditField
        function UpperEditFieldValueChanged(app, event)
            app.upper = app.UpperEditField.Value;
             startupFcn(app);
        end

        % Value changed function: LowerEditField
        function LowerEditFieldValueChanged(app, event)
            app.lower = app.LowerEditField.Value;
             startupFcn(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             app.break_points=[];
             app.signal_type=[];
             app.n_break=0;
             app.i=0;
             app.operation="null";
             app.select_signal_counter = 1;
             app.set_signal_counter = 1;
             

    startupFcn(app);
    
    
        end

        % Value changed function: Start_timeEditField
        function Start_timeEditFieldValueChanged(app, event)
           app.st = app.Start_timeEditField.Value;
            
        end

        % Value changed function: End_timeEditField
        function End_timeEditFieldValueChanged(app, event)
            app.tf = app.End_timeEditField.Value;
            
        end

        % Value changed function: FsEditField
        function FsEditFieldValueChanged(app, event)
            app.freq_sample = app.FsEditField.Value;
            
        end

        % Button pushed function: data_paramButton
        function data_paramButtonPushed(app, event)
            app.start_time=app.st;
            app.end_time=app.tf;
            app.fs=app.freq_sample;
            app.Label.Text = "1";
            app.Label_2.Text = "1";
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            app.signal = app.SignalDropDown.Value;
            
        end

        % Callback function
        function Amp_scalingCheckBoxValueChanged(app, event)
            
            app.operation = app.Amp_scalingCheckBox.Text;
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.Start_timeEditFieldLabel = uilabel(app.SignalTab);
            app.Start_timeEditFieldLabel.Text = 'Start_time';
            app.Start_timeEditField = uieditfield(app.SignalTab, 'numeric');
            app.Start_timeEditField.ValueChangedFcn = createCallbackFcn(app, @Start_timeEditFieldValueChanged, true);
            app.End_timeEditFieldLabel = uilabel(app.SignalTab);
            app.End_timeEditFieldLabel.Text = 'End_time';
            app.End_timeEditField = uieditfield(app.SignalTab, 'numeric');
            app.End_timeEditField.ValueChangedFcn = createCallbackFcn(app, @End_timeEditFieldValueChanged, true);
            app.FsEditFieldLabel = uilabel(app.SignalTab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignalTab, 'numeric');
            app.FsEditField.ValueChangedFcn = createCallbackFcn(app, @FsEditFieldValueChanged, true);
            app.Break_PointEditFieldLabel = uilabel(app.SignalTab);
            app.Break_PointEditFieldLabel.Text = 'Break_Point';
            app.Break_PointEditField = uieditfield(app.SignalTab, 'numeric');
            app.Break_PointEditField.ValueChangedFcn = createCallbackFcn(app, @Break_PointEditFieldValueChanged, true);
            app.No_BreakPointsEditFieldLabel = uilabel(app.SignalTab);
            app.No_BreakPointsEditFieldLabel.Text = {'No_Break'; 'Points'};
            app.No_BreakPointsEditField = uieditfield(app.SignalTab, 'numeric');
            app.No_BreakPointsEditField.ValueChangedFcn = createCallbackFcn(app, @No_BreakPointsEditFieldValueChanged, true);
            app.set_dataButton = uibutton(app.SignalTab, 'push');
            app.set_dataButton.ButtonPushedFcn = createCallbackFcn(app, @set_dataButtonPushed, true);
            app.set_dataButton.Text = 'set_data';
            app.signal_dataButton = uibutton(app.SignalTab, 'push');
            app.signal_dataButton.ButtonPushedFcn = createCallbackFcn(app, @signal_dataButtonPushed, true);
            app.signal_dataButton.Text = 'signal_data';
            app.DcinputsPanel = uipanel(app.SignalTab);
            app.DcinputsPanel.Title = 'Dc inputs';
            app.AmplitudeEditFieldLabel = uilabel(app.DcinputsPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.DcinputsPanel, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.TriangleinputsPanel = uipanel(app.SignalTab);
            app.TriangleinputsPanel.Title = 'Triangle inputs';
            app.AmplitudeEditField_2Label = uilabel(app.TriangleinputsPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.TriangleinputsPanel, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.Center_shiftEditFieldLabel = uilabel(app.TriangleinputsPanel);
            app.Center_shiftEditFieldLabel.Text = 'Center_shift';
            app.Center_shiftEditField = uieditfield(app.TriangleinputsPanel, 'numeric');
            app.Center_shiftEditField.ValueChangedFcn = createCallbackFcn(app, @Center_shiftEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.TriangleinputsPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.TriangleinputsPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.SininputsPanel = uipanel(app.SignalTab);
            app.SininputsPanel.Title = 'Sin inputs';
            app.AmplitudeEditField_3Label = uilabel(app.SininputsPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.SininputsPanel, 'numeric');
            app.AmplitudeEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_3ValueChanged, true);
            app.FrequencyEditFieldLabel = uilabel(app.SininputsPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SininputsPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.PhaseEditFieldLabel = uilabel(app.SininputsPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SininputsPanel, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.ExpinputsPanel = uipanel(app.SignalTab);
            app.ExpinputsPanel.Title = 'Exp inputs';
            app.AmplitudeEditField_4Label = uilabel(app.ExpinputsPanel);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.ExpinputsPanel, 'numeric');
            app.AmplitudeEditField_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_4ValueChanged, true);
            app.ExponentEditFieldLabel = uilabel(app.ExpinputsPanel);
            app.ExponentEditFieldLabel.Text = 'Exponent';
            app.ExponentEditField = uieditfield(app.ExpinputsPanel, 'numeric');
            app.ExponentEditField.ValueChangedFcn = createCallbackFcn(app, @ExponentEditFieldValueChanged, true);
            app.SincinputsPanel = uipanel(app.SignalTab);
            app.SincinputsPanel.Title = 'Sinc inputs';
            app.AmplitudeEditField_5Label = uilabel(app.SincinputsPanel);
            app.AmplitudeEditField_5Label.Text = 'Amplitude';
            app.AmplitudeEditField_5 = uieditfield(app.SincinputsPanel, 'numeric');
            app.AmplitudeEditField_5.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_5ValueChanged, true);
            app.Center_shiftEditField_2Label = uilabel(app.SincinputsPanel);
            app.Center_shiftEditField_2Label.Text = 'Center_shift';
            app.Center_shiftEditField_2 = uieditfield(app.SincinputsPanel, 'numeric');
            app.Center_shiftEditField_2.ValueChangedFcn = createCallbackFcn(app, @Center_shiftEditField_2ValueChanged, true);
            app.RampPanel = uipanel(app.SignalTab);
            app.RampPanel.Title = 'Ramp';
            app.SlopeEditFieldLabel = uilabel(app.RampPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RampPanel, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.InterceptEditFieldLabel = uilabel(app.RampPanel);
            app.InterceptEditFieldLabel.Text = 'Intercept';
            app.InterceptEditField = uieditfield(app.RampPanel, 'numeric');
            app.InterceptEditField.ValueChangedFcn = createCallbackFcn(app, @InterceptEditFieldValueChanged, true);
            app.SetButton = uibutton(app.SignalTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.data_paramButton = uibutton(app.SignalTab, 'push');
            app.data_paramButton.ButtonPushedFcn = createCallbackFcn(app, @data_paramButtonPushed, true);
            app.data_paramButton.Text = 'data_param';
            app.SignalDropDownLabel = uilabel(app.SignalTab);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.SignalTab);
            app.SignalDropDown.Items = {'Dc', 'Ramp', 'Sin', 'Sinc', 'Triangle', 'Poly', 'Exp'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = 'Dc';
            app.PolyinputsPanel = uipanel(app.SignalTab);
            app.PolyinputsPanel.Title = 'Poly inputs';
            app.n_coefficientEditFieldLabel = uilabel(app.PolyinputsPanel);
            app.n_coefficientEditFieldLabel.Text = 'n_coefficient';
            app.n_coefficientEditField = uieditfield(app.PolyinputsPanel, 'numeric');
            app.n_coefficientEditField.ValueChangedFcn = createCallbackFcn(app, @n_coefficientEditFieldValueChanged, true);
            app.coefficientEditFieldLabel = uilabel(app.PolyinputsPanel);
            app.coefficientEditFieldLabel.Text = 'coefficient';
            app.coefficientEditField = uieditfield(app.PolyinputsPanel, 'numeric');
            app.coefficientEditField.ValueChangedFcn = createCallbackFcn(app, @coefficientEditFieldValueChanged, true);
            app.EnterButton = uibutton(app.PolyinputsPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ResetButton = uibutton(app.SignalTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Label = uilabel(app.SignalTab);
            app.Label.Text = '';
            app.SignalLabel = uilabel(app.SignalTab);
            app.SignalLabel.Text = 'Signal';
            app.Label_2 = uilabel(app.SignalTab);
            app.Label_2.Text = '';
            app.VisualTab = uitab(app.TabGroup);
            app.VisualTab.Title = 'Visual';
            app.UIAxes = uiaxes(app.VisualTab);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.OperationTab = uitab(app.TabGroup);
            app.OperationTab.Title = 'Operation';
            app.UIAxes2_2 = uiaxes(app.OperationTab);
            title(app.UIAxes2_2, 'Modulated Signal')
            xlabel(app.UIAxes2_2, 'Time')
            ylabel(app.UIAxes2_2, 'Amplitude')
            zlabel(app.UIAxes2_2, 'Z')
            app.OperationButtonGroup = uibuttongroup(app.OperationTab);
            app.OperationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationButtonGroupSelectionChanged, true);
            app.OperationButtonGroup.Title = 'Operation';
            app.startButton = uiradiobutton(app.OperationButtonGroup);
            app.startButton.Visible = 'off';
            app.startButton.Text = 'start';
            app.startButton.Value = true;
            app.Amp_scalingButton = uiradiobutton(app.OperationButtonGroup);
            app.Amp_scalingButton.Text = 'Amp_scaling';
            app.Time_shiftingButton = uiradiobutton(app.OperationButtonGroup);
            app.Time_shiftingButton.Text = 'Time_shifting';
            app.Time_reverseButton = uiradiobutton(app.OperationButtonGroup);
            app.Time_reverseButton.Text = 'Time_reverse';
            app.ExpandButton = uiradiobutton(app.OperationButtonGroup);
            app.ExpandButton.Text = 'Expand';
            app.CompressButton = uiradiobutton(app.OperationButtonGroup);
            app.CompressButton.Text = 'Compress';
            app.clippingButton = uiradiobutton(app.OperationButtonGroup);
            app.clippingButton.Text = 'clipping';
            app.derivativeButton = uiradiobutton(app.OperationButtonGroup);
            app.derivativeButton.Text = 'derivative';
            app.NoneButton = uiradiobutton(app.OperationButtonGroup);
            app.NoneButton.Text = 'None';
            app.Amp_scalinginputsPanel = uipanel(app.OperationTab);
            app.Amp_scalinginputsPanel.Title = 'Amp_scaling inputs';
            app.ScaleEditFieldLabel = uilabel(app.Amp_scalinginputsPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.Amp_scalinginputsPanel, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 1;
            app.Time_shiftinginputsPanel = uipanel(app.OperationTab);
            app.Time_shiftinginputsPanel.Title = 'Time_shifting inputs';
            app.ShiftEditFieldLabel = uilabel(app.Time_shiftinginputsPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.Time_shiftinginputsPanel, 'numeric');
            app.ShiftEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftEditFieldValueChanged, true);
            app.ShiftEditField.Value = 1;
            app.ExpandinputsPanel = uipanel(app.OperationTab);
            app.ExpandinputsPanel.Title = 'Expand inputs';
            app.Expand_valEditFieldLabel = uilabel(app.ExpandinputsPanel);
            app.Expand_valEditFieldLabel.Text = 'Expand_val';
            app.Expand_valEditField = uieditfield(app.ExpandinputsPanel, 'numeric');
            app.Expand_valEditField.ValueChangedFcn = createCallbackFcn(app, @Expand_valEditFieldValueChanged, true);
            app.Expand_valEditField.Value = 1;
            app.CompressinputsPanel = uipanel(app.OperationTab);
            app.CompressinputsPanel.Title = 'Compress inputs';
            app.Compress_valEditFieldLabel = uilabel(app.CompressinputsPanel);
            app.Compress_valEditFieldLabel.Text = 'Compress_val';
            app.Compress_valEditField = uieditfield(app.CompressinputsPanel, 'numeric');
            app.Compress_valEditField.ValueChangedFcn = createCallbackFcn(app, @Compress_valEditFieldValueChanged, true);
            app.Compress_valEditField.Value = 1;
            app.ClippingPanel = uipanel(app.OperationTab);
            app.ClippingPanel.Title = 'Clipping';
            app.UpperEditFieldLabel = uilabel(app.ClippingPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.ClippingPanel, 'numeric');
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @UpperEditFieldValueChanged, true);
            app.UpperEditField.Value = 1;
            app.LowerEditFieldLabel = uilabel(app.ClippingPanel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.ClippingPanel, 'numeric');
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @LowerEditFieldValueChanged, true);
            app.LowerEditField.Value = 1;
            app.operation_visualTab = uitab(app.TabGroup);
            app.operation_visualTab.Title = 'operation_visual';
            app.UIAxes2 = uiaxes(app.operation_visualTab);
            title(app.UIAxes2, 'Modulated Signal')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end